classdef ZCreateDatabase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RealTimeFaceRecognitionCreateDatabaseLabel  matlab.ui.control.Label
        StartCameraButton     matlab.ui.control.Button
        CaptureButton         matlab.ui.control.Button
        BackButton            matlab.ui.control.Button
        SaveinDatabaseButton  matlab.ui.control.Button
        MenuLabel             matlab.ui.control.Label
        InputFromWebcamLabel  matlab.ui.control.Label
        CaptureImageLabel     matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
    end

    properties (Access = public)
        MainHandle % Jembatan antar-GUI
    end
    
    properties (Access = private)
        cam              % Webcam object
        camTimer         % Timer for update video real time
        capturedImage    % Menyimpan hasil tangkapan gambar terakhir
        facenet          % Model FaceNet
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(app, event)
            try
                % Inisialisasi webcam
                app.cam = webcam;

                % Mulai timer untuk ambil tiap 0.1 detik
                app.camTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~) updateFrame(app));

                start(app.camTimer);

            catch ME
                uialert(app.UIFigure, ['Gagal mengakses webcam: ' ME.message], 'Eror');
            end
        end

        function updateFrame(app)
            try
                % Ambil gambar dari webcam
                frame = snapshot(app.cam); 

                % Resize ke ukuran tetap, misalnya 500x500 piksel
                resizedGray = imresize(frame, [500 500]);

                % Tampilkan di UIAxes1
                imshow(resizedGray, 'Parent', app.UIAxes);
                axis(app.UIAxes, 'image');
            catch
                % Diamkan kalau error
            end
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            try
                % Ambil frame dari webcam
                if isempty(app.cam)
                    uialert(app.UIFigure, 'Kamera belum aktif.', 'Peringatan');
                    return;
                end

                % Ambil gambar dari webcam
                frame = snapshot(app.cam);

                % Ubah ke grayscale (hitam putih)
                grayFrame = rgb2gray(frame);

                % Resize ke ukuran tetap, misalnya 500x500 piksel
                resizedGray = imresize(grayFrame, [500 500]);

                % Tampilkan di UIAxes2
                imshow(resizedGray, 'Parent', app.UIAxes2);
                axis(app.UIAxes2, 'image');

                % (Opsional) Simpan hasil capture ke properti
                app.capturedImage = frame;

                % Matikan webcam langsung setelah capture
                clear app.cam
                app.cam = [];

            catch ME
                uialert(app.UIFigure, ['Gagal capture: ' ME.message], 'Eror');
            end
        end

        % Button pushed function: SaveinDatabaseButton
        function SaveinDatabaseButtonPushed(app, event)
            try
                % 1. Pastikan gambar sudah dicapture
                if isempty(app.capturedImage)
                    uialert(app.UIFigure, 'Belum ada gambar yang di capture.', 'Peringatan');
                    return;
                end
        
                % 2. Deteksi wajah
                faceDetector = vision.CascadeObjectDetector();
                bbox = step(faceDetector, app.capturedImage);
                if isempty(bbox)
                    uialert(app.UIFigure, 'Wajah tidak terdeteksi. Coba capture ulang.', 'Peringatan');
                    return;
                end
        
                % 3. Crop dan resize wajah
                cropFace = imcrop(app.capturedImage, bbox(1,:));
                resizedFace = imresize(cropFace, [160 160]);
        
                % 4. Pilih folder & nama file
                [fileName, folderPath] = uiputfile({'*.jpg'}, 'Simpan Gambar ke Folder');
                if isequal(fileName, 0) || isequal(folderPath, 0)
                    uialert(app.UIFigure, 'Penyimpanan dibatalkan.', 'Info');
                    return;
                end
                fullPath = fullfile(folderPath, fileName);
        
                % 5. Simpan gambar wajah
                imwrite(resizedFace, fullPath);
        
                % 6. Load model FaceNet jika belum ada
                if isempty(app.facenet)
                    app.facenet = importNetworkFromONNX("facenet.onnx");
                end
        
                % 7. Baca ulang semua file gambar .jpg di folder tsb
                imageFiles = dir(fullfile(folderPath, '*.jpg'));
                numFiles = length(imageFiles);
        
                allEmbeddings = zeros(numFiles, 128);
                allLabels = strings(numFiles, 1);
        
                for i = 1:numFiles
                    imgPath = fullfile(folderPath, imageFiles(i).name);
                    img = imread(imgPath);
        
                    % Pastikan RGB
                    if size(img,3) == 1
                        img = repmat(img, 1, 1, 3);
                    end
                    img = imresize(img, [160 160]);
        
                    % Embedding
                    emb = ZgetFaceEmbedding(img, app.facenet);
                    allEmbeddings(i, :) = emb;
                    allLabels(i) = erase(imageFiles(i).name, '.jpg');
                end
        
                % 8. Simpan ulang file data.mat di lokasi proyek utama
                save(fullfile(pwd, "data.mat"), "allEmbeddings", "allLabels");
        
                % 9. Beri notifikasi sukses
                uialert(app.UIFigure, ...
                    ['Wajah berhasil disimpan sebagai "' fileName '" dan database diperbarui.'], ...
                    'Berhasil');
        
            catch ME
                uialert(app.UIFigure, ['Gagal menyimpan data: ' ME.message], 'Error');
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Tampilkan kembali GUI 1
            app.MainHandle.UIFigure.Visible = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                % Stop timer jika masih jalan
                if ~isempty(app.camTimer) && isvalid(app.camTimer)
                stop(app.camTimer);
                delete(app.camTimer);
                end

                % Hentikan kamera
                if ~isempty(app.cam)
                    clear app.cam;
                end

            catch
                % Abaikan error
            end

            % Lanjutkan proses penutupan app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.CaptureImageLabel = uilabel(app.UIFigure);
            app.CaptureImageLabel.Text = 'Capture Image';
            app.InputFromWebcamLabel = uilabel(app.UIFigure);
            app.InputFromWebcamLabel.Text = 'Input From Webcam';
            app.MenuLabel = uilabel(app.UIFigure);
            app.MenuLabel.Text = ' Menu';
            app.SaveinDatabaseButton = uibutton(app.UIFigure, 'push');
            app.SaveinDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinDatabaseButtonPushed, true);
            app.SaveinDatabaseButton.Text = 'Save in Database';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.StartCameraButton = uibutton(app.UIFigure, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.RealTimeFaceRecognitionCreateDatabaseLabel = uilabel(app.UIFigure);
            app.RealTimeFaceRecognitionCreateDatabaseLabel.Text = 'Real Time Face Recognition (Create Database)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZCreateDatabase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end