classdef ZFaceRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RealTimeFaceRecognitionLabel  matlab.ui.control.Label
        StartCameraButton             matlab.ui.control.Button
        CaptureButton                 matlab.ui.control.Button
        SelectDatabaseButton          matlab.ui.control.Button
        RecognitionButton             matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        MenuLabel                     matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        JarakLabel                    matlab.ui.control.Label
        LDRLabel                      matlab.ui.control.Label
        lblResponseTime               matlab.ui.control.Label
        InputFromWebcamLabel          matlab.ui.control.Label
        CaptureImageLabel             matlab.ui.control.Label
        RecognitionLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainHandle          % Jembatan antar-GUI
        trainDatabasePath   % Path ke folder TrainDatabase
        facenet             % Model ONNX yang dimuat sekali saja
        queryEmbedding
        databaseEmbeddings
        databaseLabels
        databaseFolderPath  % diset saat Select Database
        arduinoObj          % Koneksi ke Arduino Uno % Description
        arduinoTimer        % Description
        sensorTimer
    end
    
    properties (Access = private)
        cam              % Webcam object
        camTimer         % Timer for update video real time
        capturedImage    % Menyimpan hasil tangkapan gambar terakhir
        startTime
        elapsedTime
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Inisialisasi (misalnya di StartupFcn atau saat GUI dijalankan pertama kali)
            if isempty(app.arduinoObj) || ~isa(app.arduinoObj, 'serialport')
                app.arduinoObj = serialport("COM4", 9600);  % Ganti COM-nya sesuai Arduino
                configureTerminator(app.arduinoObj, "LF");
                flush(app.arduinoObj);  % Bersihkan buffer
            end

            % Membuat timer untuk memantau sinyal balik dari Arduino secara periodik
            app.arduinoTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1.0, ...
                'TimerFcn', @(~,~)checkArduinoFeedback(app));
            
            start(app.arduinoTimer);
        
            % Cek apakah Arduino mengirimkan sinyal balik
            function checkArduinoFeedback(app)
            if app.arduinoObj.NumBytesAvailable > 0
                msg = readline(app.arduinoObj);
                if contains(msg, 'I')
                    %app.StatusLabel.Text = '🚨 Orang asing terdeteksi!';
                    % bisa tambahkan notifikasi visual/audio di GUI
                end
            end
            end

            % Membuat timer untuk pembacaan sensor
            app.sensorTimer = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period',0.5, ...
                'TimerFcn',@(~,~) updateSensor(app));
            start(app.sensorTimer);

            % Pembacaan sensor LDR dan Ultrasonik
            function updateSensor(app)
            if app.arduinoObj.NumBytesAvailable > 0
                try
                    line = readline(app.arduinoObj);
                    if contains(line, "LDR") && contains(line, "US")
                        parts = split(line, ",");
                        ldrVal = str2double(extractAfter(parts{1}, "LDR:"));
                        usVal = str2double(extractAfter(parts{2}, "US:"));
        
                        app.LDRLabel.Text = ['Tingkat Cahaya: ', num2str(ldrVal), ' lux'];
                        app.JarakLabel.Text = ['Jarak Pengguna: ', num2str(usVal), ' cm'];
                    end
                catch
                    % Jika error parsing, abaikan
                end
            end
            end

            app.startTime = 0;
            app.elapsedTime = 0;
            app.lblResponseTime.Text = 'Waktu Respons: 0000'; % Format awal saat GUI dibuka
        end

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(app, event)
            try
                % Inisialisasi webcam
                app.cam = webcam;

                app.StatusLabel.Text = 'Webcam Siap Menangkap Gambar...';

                % Mulai timer untuk ambil tiap 0.1 detik
                app.camTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~) updateFrame(app));

                start(app.camTimer);
                
            catch ME
                uialert(app.UIFigure, ['Gagal mengakses webcam: ' ME.message], 'Eror');
            end
        end

        function updateFrame(app)
            try
                % Ambil gambar dari webcam
                frame = snapshot(app.cam); 

                % Resize ke ukuran tetap, misalnya 500x500 piksel
                resizedGray = imresize(frame, [500 500]);

                % Tampilkan di UIAxes1
                imshow(resizedGray, 'Parent', app.UIAxes);
                axis(app.UIAxes, 'image');
            catch
                % Diamkan kalau error
            end
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            try
                % Ambil frame dari webcam
                if isempty(app.cam)
                    uialert(app.UIFigure, 'Kamera belum aktif.', 'Peringatan');
                    return;
                end

                app.StatusLabel.Text = 'Penangkapan Gambar untuk Dikenali...';

                % Ambil gambar dari webcam
                frame = snapshot(app.cam);

                % Ubah ke grayscale (hitam putih)
                %grayFrame = rgb2gray(frame);

                % Resize ke ukuran tetap, misalnya 500x500 piksel
                resizedGray = imresize(frame, [500 500]);

                % Tampilkan di UIAxes2
                imshow(resizedGray, 'Parent', app.UIAxes2);
                axis(app.UIAxes2, 'image');

                %(Opsional) Simpan hasil capture ke properti
                app.capturedImage = resizedGray;

                % Matikan webcam langsung setelah capture
                clear app.cam
                app.cam = [];

            catch ME
                uialert(app.UIFigure, ['Gagal capture: ' ME.message], 'Eror');
            end
        end

        % Button pushed function: SelectDatabaseButton
        function SelectDatabaseButtonPushed(app, event)
            try
                % Validasi apakah gambar sudah dicapture
                if isempty(app.capturedImage)
                    uialert(app.UIFigure, 'Belum ada gambar hasil capture.', 'Peringatan');
                    return;
                end

                app.StatusLabel.Text = 'Pemilihan Database...';
        
                % Muat model FaceNet jika belum ada
                if isempty(app.facenet)
                    app.facenet = importNetworkFromONNX("facenet.onnx");
                end
        
                % === 1. Deteksi wajah menggunakan Haar Cascade ===
                faceDetector = vision.CascadeObjectDetector();
                bbox = step(faceDetector, app.capturedImage);
        
                if isempty(bbox)
                    uialert(app.UIFigure, 'Wajah tidak terdeteksi pada gambar.', 'Peringatan');
                    return;
                end
        
                % Ambil wajah pertama yang terdeteksi
                faceCrop = imcrop(app.capturedImage, bbox(1, :));
        
                % Resize ke 160x160 dan pastikan RGB
                faceResized = imresize(faceCrop, [160 160]);
                if size(faceResized, 3) == 1
                    faceResized = repmat(faceResized, 1, 1, 3); % Convert grayscale ke RGB
                end
        
                % === 2. Dapatkan sidik wajah 128x1 ===
                app.queryEmbedding = ZgetFaceEmbedding(faceResized, app.facenet);
        
                % === 3. Pilih file .mat yang berisi data database ===
                [file, path] = uigetfile('*.mat', 'Pilih file data.mat (sidik wajah database)');
                folderPath = uigetdir(pwd, 'Pilih folder TrainDatabase');
                if isequal(file, 0) || isequal(folderPath,0)
                    uialert(app.UIFigure, 'Pemilihan file dan folder dibatalkan.', 'Info');
                    return;
                end
                
                fullPath = fullfile(path, file);
        
                % Load data dari file .mat
                db = load(fullPath);
        
                % Validasi isi data
                if ~isfield(db, 'allEmbeddings') || ~isfield(db, 'allLabels')
                    uialert(app.UIFigure, 'File tidak mengandung embeddings dan labels.', 'Error');
                    return;
                end
        
                % Simpan ke properti app untuk digunakan saat recognition
                app.trainDatabasePath = folderPath;
                app.databaseEmbeddings = db.allEmbeddings;
                app.databaseLabels = db.allLabels;
        
                % Tampilkan alert sukses
                uialert(app.UIFigure, 'Berhasil memuat database dan menghasilkan sidik wajah baru.', 'Sukses');
        
            catch ME
                uialert(app.UIFigure, ['Gagal: ' ME.message], 'Error');
            end
        end

        % Button pushed function: RecognitionButton
        function RecognitionButtonPushed(app, event)
            try
                % Validasi apakah query dan database sudah ada
                if isempty(app.queryEmbedding)
                    uialert(app.UIFigure, 'Belum ada sidik wajah baru (query).', 'Peringatan');
                    return;
                end
                if isempty(app.databaseEmbeddings) || isempty(app.databaseLabels)
                    uialert(app.UIFigure, 'Belum ada data database wajah.', 'Peringatan');
                    return;
                end

                app.StatusLabel.Text = 'Pencocokan Wajah Baru dengan Database...';

                % Tombol Recognition ditekan
                app.startTime = tic; % Mulai stopwatch
        
                % Hitung jarak Euclidean antara query dengan semua database
                query = app.queryEmbedding(:)';  % pastikan jadi [1 x 128]
                db = app.databaseEmbeddings;     % [N x 128]

                if size(db,2) ~= 128
                    error('Database embedding tidak valid, kolom ≠ 128');
                end

                distances = vecnorm(db - query, 2, 2);
                [sortedDistances, sortedIdx] = sort(distances);
        
                % Ambil Top-1 dan Top-2 distance
                top1Dist = sortedDistances(1);
                top2Dist = sortedDistances(2);
                top1Idx = sortedIdx(1);
                top1Label = app.databaseLabels(top1Idx);
                
                % Rasio
                ratio = top1Dist / top2Dist;
                
                % Thresholds
                threshold = 1.0;         % Jarak minimum yang diterima
                thresholdRatio = 1.0;    % Rasio Top-1 vs Top-2
                
                % Evaluasi kondisi gabungan
                if top1Dist < threshold && ratio < thresholdRatio
                    % Wajah dikenali
                    app.StatusLabel.Text = sprintf("✅ Dikenali: %s | Jarak: %.2f | Rasio: %.2f", top1Label, top1Dist, ratio);
                    writeline(app.arduinoObj, '1');  % Wajah dikenali

                    % Tampilkan gambar yang dikenali
                    matchedImgPath = fullfile(app.trainDatabasePath, top1Label + ".jpg");
                    if isfile(matchedImgPath)
                        matchedImg = imread(matchedImgPath);
                        imshow(matchedImg, 'Parent', app.UIAxes3);
                    end
                else
                    % Tidak dikenali
                    app.StatusLabel.Text = sprintf("❌ Tidak dikenali | Jarak: %.2f | Rasio: %.2f | Terdekat: %s", ...
                        top1Dist, ratio, top1Label);
                    writeline(app.arduinoObj, '0');  % Wajah tidak dikenali
                    
                    cla(app.UIAxes3);
                end

                % Setelah pengenalan wajah selesai
                app.elapsedTime = toc(app.startTime); % Hitung waktu
                app.lblResponseTime.Text = sprintf('Waktu Respons: %.3f', app.elapsedTime); % Tampilkan di label (detik.milidetik)
            catch ME
                uialert(app.UIFigure, ['Gagal melakukan recognition: ' ME.message], 'Error');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            try
                if ~isempty(app.arduinoObj) && isvalid(app.arduinoObj)
                    writeline(app.arduinoObj, 'R');  % Kirim sinyal reset ke Arduino
                    uialert(app.UIFigure, 'Sinyal reset dikirim ke Arduino Uno.', 'Berhasil');
                else
                    uialert(app.UIFigure, 'Arduino belum terhubung.', 'Gagal');
                end

                app.startTime = 0;
                app.elapsedTime = 0;
                app.lblResponseTime.Text = 'Waktu Respons: 0000'; % Reset waktu
            catch ME
                uialert(app.UIFigure, ['Gagal reset: ' ME.message], 'Error');
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Tampilkan kembali GUI 1
            app.MainHandle.UIFigure.Visible = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                % Stop timer jika masih jalan
                if ~isempty(app.camTimer) && isvalid(app.camTimer)
                stop(app.camTimer);
                delete(app.camTimer);
                end

                % Stop timer pembacaan sensor
                if isvalid(app.sensorTimer)
                    stop(app.sensorTimer);
                    delete(app.sensorTimer);
                end

                % Hentikan kamera
                if ~isempty(app.cam)
                    clear app.cam;
                end

            catch
                % Abaikan error
            end

            % Lanjutkan proses penutupan app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.RecognitionLabel = uilabel(app.UIFigure);
            app.RecognitionLabel.Text = 'Recognition';
            app.CaptureImageLabel = uilabel(app.UIFigure);
            app.CaptureImageLabel.Text = 'Capture Image';
            app.InputFromWebcamLabel = uilabel(app.UIFigure);
            app.InputFromWebcamLabel.Text = 'Input From Webcam';
            app.lblResponseTime = uilabel(app.UIFigure);
            app.lblResponseTime.Text = ' Waktu Respons:';
            app.LDRLabel = uilabel(app.UIFigure);
            app.LDRLabel.Text = ' Tingkat Cahaya:';
            app.JarakLabel = uilabel(app.UIFigure);
            app.JarakLabel.Text = ' Jarak Pengguna:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' Status:';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            app.MenuLabel = uilabel(app.UIFigure);
            app.MenuLabel.Text = ' Menu';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RecognitionButton = uibutton(app.UIFigure, 'push');
            app.RecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @RecognitionButtonPushed, true);
            app.RecognitionButton.Text = 'Recognition';
            app.SelectDatabaseButton = uibutton(app.UIFigure, 'push');
            app.SelectDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDatabaseButtonPushed, true);
            app.SelectDatabaseButton.Text = 'Select Database';
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.StartCameraButton = uibutton(app.UIFigure, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.RealTimeFaceRecognitionLabel = uilabel(app.UIFigure);
            app.RealTimeFaceRecognitionLabel.Text = 'Real Time Face Recognition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZFaceRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end