classdef ZMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CreateDatabaseButton   matlab.ui.control.Button
        FaceRecognitionButton  matlab.ui.control.Button
        ExitButton             matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateDatabaseButton
        function CreateDatabaseButtonPushed(app, event)
            createdatabase = ZCreateDatabase;
            createdatabase.MainHandle = app;
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: FaceRecognitionButton
        function FaceRecognitionButtonPushed(app, event)
            facerecognition = ZFaceRecognition;
            facerecognition.MainHandle = app;
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.FaceRecognitionButton = uibutton(app.UIFigure, 'push');
            app.FaceRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @FaceRecognitionButtonPushed, true);
            app.FaceRecognitionButton.Text = 'Face Recognition';
            app.CreateDatabaseButton = uibutton(app.UIFigure, 'push');
            app.CreateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDatabaseButtonPushed, true);
            app.CreateDatabaseButton.Text = 'Create Database';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end